classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        StarRadiusSolarRadiusSliderLabel  matlab.ui.control.Label
        StarRadiusSolarRadiusSlider   matlab.ui.control.Slider
        StarMassSolarMassSliderLabel  matlab.ui.control.Label
        StarMassSolarMassSlider       matlab.ui.control.Slider
        EditField                     matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        ExitButton                    matlab.ui.control.Button
    end


    methods (Access = private)

        % Value changed function: StarRadiusSolarRadiusSlider
        function StarRadiusSolarRadiusSliderValueChanged(app, event)
            % Last value of the star radius selected with the slider
            R_star = app.StarRadiusSolarRadiusSlider.Value ;
            M_star = app.StarMassSolarMassSlider.Value ;
            % Set the edit field value 
            app.EditField.Value = R_star;  
            % Definition of the star
            STAR = Define_star(R_star * 695510, M_star * 1.989e30);
            % Graphical representation of the star
            make_Star(STAR,app.UIAxes)
            app.UIAxes.Color = 'black'; 
            % Text box and image
            app.TextArea.Value=text_selec(STAR);
            % Saving the star values
            save('Star_values.mat','STAR')
            
            
        end

        % Value changed function: StarMassSolarMassSlider
        function StarMassSolarMassSliderValueChanged(app, event)
            % Last value of the star radius selected with the slider
            M_star = app.StarMassSolarMassSlider.Value;
            R_star = app.StarRadiusSolarRadiusSlider.Value;
            %Set the edit field value 
            app.EditField_2.Value = M_star;
            % Definition of the star
            STAR = Define_star(R_star * 695510, M_star * 1.989e30);
            % Graphical representation of the star
            make_Star(STAR,app.UIAxes)
            app.UIAxes.Color = 'black'; 
            % Text box and image
            app.TextArea.Value=text_selec(STAR);
            % Saving the star values
            save('Star_values.mat','STAR')
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
                 Figurename = app.UIFigure ;
                 close(app.UIFigure)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StarRadiusSolarRadiusSliderLabel = uilabel(app.UIFigure);
            app.StarRadiusSolarRadiusSliderLabel.Text = 'Star Radius [Solar Radius]';
            app.StarRadiusSolarRadiusSlider = uislider(app.UIFigure);
            app.StarRadiusSolarRadiusSlider.Limits = [1 43];
            app.StarRadiusSolarRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @StarRadiusSolarRadiusSliderValueChanged, true);
            app.StarRadiusSolarRadiusSlider.Value = 1;
            app.StarMassSolarMassSliderLabel = uilabel(app.UIFigure);
            app.StarMassSolarMassSliderLabel.Text = 'Star Mass [Solar Mass]';
            app.StarMassSolarMassSlider = uislider(app.UIFigure);
            app.StarMassSolarMassSlider.Limits = [0.5 15];
            app.StarMassSolarMassSlider.ValueChangedFcn = createCallbackFcn(app, @StarMassSolarMassSliderValueChanged, true);
            app.StarMassSolarMassSlider.Value = 0.5;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end