classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        DistancefromStarAUSliderLabel   matlab.ui.control.Label
        DistancefromStarAUSlider        matlab.ui.control.Slider
        PlanetMassEarthMassSliderLabel  matlab.ui.control.Label
        PlanetMassEarthMassSlider       matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        PlanetRadiusEarthRadiusSliderLabel  matlab.ui.control.Label
        PlanetRadiusEarthRadiusSlider   matlab.ui.control.Slider
        EditField_3                     matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RockyPlanetButton               matlab.ui.control.RadioButton
        GaseousPlanetButton             matlab.ui.control.RadioButton
        ExitButton                      matlab.ui.control.Button
        SurfacecompositionLabel         matlab.ui.control.Label
        GlacialIceEditFieldLabel        matlab.ui.control.Label
        GlacialIceEditField             matlab.ui.control.NumericEditField
        FreshSnowEditFieldLabel         matlab.ui.control.Label
        FreshSnowEditField              matlab.ui.control.NumericEditField
        RedSandEditFieldLabel           matlab.ui.control.Label
        RedSandEditField                matlab.ui.control.NumericEditField
        LiquidWaterEditFieldLabel       matlab.ui.control.Label
        LiquidWaterEditField            matlab.ui.control.NumericEditField
        DarkRegolithEditFieldLabel      matlab.ui.control.Label
        DarkRegolithEditField           matlab.ui.control.NumericEditField
        GrayRegolithEditFieldLabel      matlab.ui.control.Label
        GrayRegolithEditField           matlab.ui.control.NumericEditField
        WhiteSurfaceEditFieldLabel      matlab.ui.control.Label
        WhiteSurfaceEditField           matlab.ui.control.NumericEditField
        ClaySoilEditFieldLabel          matlab.ui.control.Label
        ClaySoilEditField               matlab.ui.control.NumericEditField
        GasJovianEditFieldLabel         matlab.ui.control.Label
        GasJovianEditField              matlab.ui.control.NumericEditField
        GasSaturnianEditFieldLabel      matlab.ui.control.Label
        GasSaturnianEditField           matlab.ui.control.NumericEditField
        PLANETButton_2                  matlab.ui.control.StateButton
        SYSTEMButton_2                  matlab.ui.control.StateButton
        TextArea                        matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Value changed function: DistancefromStarAUSlider
        function DistancefromStarAUSliderValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
            % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Set the edit field value 
            app.EditField.Value = d_planet;  
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Futher computations
            load('Star_values.mat')
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
            ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
            % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: PlanetMassEarthMassSlider
        function PlanetMassEarthMassSliderValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Set the edit field value 
            app.EditField_2.Value = M_planet;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Futher computations
            load('Star_values.mat')
            d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: PlanetRadiusEarthRadiusSlider
        function PlanetRadiusEarthRadiusSliderValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Set the edit field value 
            app.EditField_3.Value = R_planet;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end

        % Callback function
        function PLANETButtonPushed(app, event)

        end

        % Callback function
        function SYSTEMButtonPushed(app, event)

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
                  % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
                 Figurename = app.UIFigure ;
                 close(app.UIFigure)
        end

        % Value changed function: PLANETButton_2
        function PLANETButton_2ValueChanged(app, event)
                      % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: GlacialIceEditField
        function GlacialIceEditFieldValueChanged(app, event)
                    % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                      
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: FreshSnowEditField
        function FreshSnowEditFieldValueChanged(app, event)
                      % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          
            
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end

        % Value changed function: RedSandEditField
        function RedSandEditFieldValueChanged(app, event)
                     % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: LiquidWaterEditField
        function LiquidWaterEditFieldValueChanged(app, event)
         % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: DarkRegolithEditField
        function DarkRegolithEditFieldValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end

        % Value changed function: GrayRegolithEditField
        function GrayRegolithEditFieldValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
          
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: ClaySoilEditField
        function ClaySoilEditFieldValueChanged(app, event)
            % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                       
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
              % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end

        % Value changed function: WhiteSurfaceEditField
        function WhiteSurfaceEditFieldValueChanged(app, event)
                   % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                    
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
             % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: GasJovianEditField
        function GasJovianEditFieldValueChanged(app, event)
                      % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
          
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
             % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: GasSaturnianEditField
        function GasSaturnianEditFieldValueChanged(app, event)
                      % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
             ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
             % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
            
        end

        % Value changed function: SYSTEMButton_2
        function SYSTEMButton_2ValueChanged(app, event)
                       % Inputs: Distance from Sun, Planet mass and Planet radius
            d_planet = app.DistancefromStarAUSlider.Value;
            M_planet = app.PlanetMassEarthMassSlider.Value;            
            R_planet = app.PlanetRadiusEarthRadiusSlider.Value;
            % Planet type
            if app.RockyPlanetButton.Value == 1
                planet_type = 1;
             else
                 planet_type = 0;
            end
            
             % Composition
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            user_selection(1) = app.GlacialIceEditField.Value;
            user_selection(2) = app.FreshSnowEditField.Value;
            user_selection(3) = app.RedSandEditField.Value;
            user_selection(4) = app.LiquidWaterEditField.Value;
            user_selection(5) = app.DarkRegolithEditField.Value;
            user_selection(6) = app.GrayRegolithEditField.Value;
            user_selection(7) = app.ClaySoilEditField.Value;
            user_selection(8) = app.WhiteSurfaceEditField.Value;
            user_selection(9) = app.GasJovianEditField.Value;
            user_selection(10) = app.GasSaturnianEditField.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        
            % Futher computations
            load('Star_values.mat')
                        d_planet = d_planet * 1.496e8;
            M_planet = M_planet * 5.972e24;
            R_planet = R_planet * 6371;
            PLANET = Define_Planet(STAR, R_planet,M_planet,d_planet,planet_type);
            SYSTEM = Define_system(STAR);
            SURFACES = SurfaceAlbedos(user_selection);
            ALBEDO = Compute_Albedo(SYSTEM,PLANET,SURFACES);
            ATMOSPHERE = Define_Atmos(STAR,PLANET,ALBEDO);
            
            %Plots
            if app.PLANETButton_2.Value ==1
                make_Planet(PLANET,SURFACES,app.UIAxes)
            elseif app.SYSTEMButton_2.Value ==1
                make_System(STAR,SYSTEM,PLANET,app.UIAxes)
            end
            
            app.UIAxes.Color = 'black'; 
            
             % Text box
            app.TextArea.Value = planet_text(PLANET,ATMOSPHERE,planet_type);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DistancefromStarAUSliderLabel = uilabel(app.UIFigure);
            app.DistancefromStarAUSliderLabel.Text = 'Distance from Star [AU]';
            app.DistancefromStarAUSlider = uislider(app.UIFigure);
            app.DistancefromStarAUSlider.Limits = [1 100];
            app.DistancefromStarAUSlider.ValueChangedFcn = createCallbackFcn(app, @DistancefromStarAUSliderValueChanged, true);
            app.DistancefromStarAUSlider.Value = 1;
            app.PlanetMassEarthMassSliderLabel = uilabel(app.UIFigure);
            app.PlanetMassEarthMassSliderLabel.Text = 'Planet Mass [Earth Mass]';
            app.PlanetMassEarthMassSlider = uislider(app.UIFigure);
            app.PlanetMassEarthMassSlider.Limits = [0.5 4000];
            app.PlanetMassEarthMassSlider.ValueChangedFcn = createCallbackFcn(app, @PlanetMassEarthMassSliderValueChanged, true);
            app.PlanetMassEarthMassSlider.Value = 0.5;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PlanetRadiusEarthRadiusSliderLabel = uilabel(app.UIFigure);
            app.PlanetRadiusEarthRadiusSliderLabel.Text = 'Planet Radius [Earth Radius]';
            app.PlanetRadiusEarthRadiusSlider = uislider(app.UIFigure);
            app.PlanetRadiusEarthRadiusSlider.Limits = [0.1 120];
            app.PlanetRadiusEarthRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @PlanetRadiusEarthRadiusSliderValueChanged, true);
            app.PlanetRadiusEarthRadiusSlider.Value = 0.1;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RockyPlanetButton = uiradiobutton(app.ButtonGroup);
            app.RockyPlanetButton.Text = 'Rocky Planet';
            app.RockyPlanetButton.Value = true;
            app.GaseousPlanetButton = uiradiobutton(app.ButtonGroup);
            app.GaseousPlanetButton.Text = 'Gaseous Planet';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SurfacecompositionLabel = uilabel(app.UIFigure);
            app.SurfacecompositionLabel.Text = 'Surface composition';
            app.GlacialIceEditFieldLabel = uilabel(app.UIFigure);
            app.GlacialIceEditFieldLabel.Text = 'Glacial Ice';
            app.GlacialIceEditField = uieditfield(app.UIFigure, 'numeric');
            app.GlacialIceEditField.ValueChangedFcn = createCallbackFcn(app, @GlacialIceEditFieldValueChanged, true);
            app.FreshSnowEditFieldLabel = uilabel(app.UIFigure);
            app.FreshSnowEditFieldLabel.Text = 'Fresh Snow';
            app.FreshSnowEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreshSnowEditField.ValueChangedFcn = createCallbackFcn(app, @FreshSnowEditFieldValueChanged, true);
            app.RedSandEditFieldLabel = uilabel(app.UIFigure);
            app.RedSandEditFieldLabel.Text = 'Red Sand';
            app.RedSandEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedSandEditField.ValueChangedFcn = createCallbackFcn(app, @RedSandEditFieldValueChanged, true);
            app.LiquidWaterEditFieldLabel = uilabel(app.UIFigure);
            app.LiquidWaterEditFieldLabel.Text = 'Liquid Water';
            app.LiquidWaterEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiquidWaterEditField.ValueChangedFcn = createCallbackFcn(app, @LiquidWaterEditFieldValueChanged, true);
            app.DarkRegolithEditFieldLabel = uilabel(app.UIFigure);
            app.DarkRegolithEditFieldLabel.Text = 'Dark Regolith';
            app.DarkRegolithEditField = uieditfield(app.UIFigure, 'numeric');
            app.DarkRegolithEditField.ValueChangedFcn = createCallbackFcn(app, @DarkRegolithEditFieldValueChanged, true);
            app.GrayRegolithEditFieldLabel = uilabel(app.UIFigure);
            app.GrayRegolithEditFieldLabel.Text = 'Gray Regolith ';
            app.GrayRegolithEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrayRegolithEditField.ValueChangedFcn = createCallbackFcn(app, @GrayRegolithEditFieldValueChanged, true);
            app.WhiteSurfaceEditFieldLabel = uilabel(app.UIFigure);
            app.WhiteSurfaceEditFieldLabel.Text = 'White Surface';
            app.WhiteSurfaceEditField = uieditfield(app.UIFigure, 'numeric');
            app.WhiteSurfaceEditField.ValueChangedFcn = createCallbackFcn(app, @WhiteSurfaceEditFieldValueChanged, true);
            app.ClaySoilEditFieldLabel = uilabel(app.UIFigure);
            app.ClaySoilEditFieldLabel.Text = 'Clay Soil';
            app.ClaySoilEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClaySoilEditField.ValueChangedFcn = createCallbackFcn(app, @ClaySoilEditFieldValueChanged, true);
            app.GasJovianEditFieldLabel = uilabel(app.UIFigure);
            app.GasJovianEditFieldLabel.Text = 'Gas Jovian';
            app.GasJovianEditField = uieditfield(app.UIFigure, 'numeric');
            app.GasJovianEditField.ValueChangedFcn = createCallbackFcn(app, @GasJovianEditFieldValueChanged, true);
            app.GasSaturnianEditFieldLabel = uilabel(app.UIFigure);
            app.GasSaturnianEditFieldLabel.Text = 'Gas Saturnian';
            app.GasSaturnianEditField = uieditfield(app.UIFigure, 'numeric');
            app.GasSaturnianEditField.ValueChangedFcn = createCallbackFcn(app, @GasSaturnianEditFieldValueChanged, true);
            app.PLANETButton_2 = uibutton(app.UIFigure, 'state');
            app.PLANETButton_2.ValueChangedFcn = createCallbackFcn(app, @PLANETButton_2ValueChanged, true);
            app.PLANETButton_2.Text = 'PLANET';
            app.SYSTEMButton_2 = uibutton(app.UIFigure, 'state');
            app.SYSTEMButton_2.ValueChangedFcn = createCallbackFcn(app, @SYSTEMButton_2ValueChanged, true);
            app.SYSTEMButton_2.Text = 'SYSTEM';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end